classdef CCEP_file < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ManualInspectionTab            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes_1                       matlab.ui.control.UIAxes
        ChButton_1                     matlab.ui.control.StateButton
        UIAxes_2                       matlab.ui.control.UIAxes
        ChButton_2                     matlab.ui.control.StateButton
        UIAxes_3                       matlab.ui.control.UIAxes
        ChButton_3                     matlab.ui.control.StateButton
        UIAxes_4                       matlab.ui.control.UIAxes
        ChButton_4                     matlab.ui.control.StateButton
        UIAxes_5                       matlab.ui.control.UIAxes
        ChButton_5                     matlab.ui.control.StateButton
        UIAxes_6                       matlab.ui.control.UIAxes
        ChButton_6                     matlab.ui.control.StateButton
        UIAxes_7                       matlab.ui.control.UIAxes
        ChButton_7                     matlab.ui.control.StateButton
        UIAxes_8                       matlab.ui.control.UIAxes
        ChButton_8                     matlab.ui.control.StateButton
        UIAxes_9                       matlab.ui.control.UIAxes
        ChButton_9                     matlab.ui.control.StateButton
        UIAxes_10                      matlab.ui.control.UIAxes
        ChButton_10                    matlab.ui.control.StateButton
        UIAxes_11                      matlab.ui.control.UIAxes
        ChButton_11                    matlab.ui.control.StateButton
        UIAxes_12                      matlab.ui.control.UIAxes
        ChButton_12                    matlab.ui.control.StateButton
        UIAxes_13                      matlab.ui.control.UIAxes
        ChButton_13                    matlab.ui.control.StateButton
        UIAxes_14                      matlab.ui.control.UIAxes
        ChButton_14                    matlab.ui.control.StateButton
        UIAxes_15                      matlab.ui.control.UIAxes
        ChButton_15                    matlab.ui.control.StateButton
        BadChannelsLabel               matlab.ui.control.Label
        Left                           matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        Right                          matlab.ui.control.Button
        ConfirmBadChannelsButton       matlab.ui.control.Button
        NoiseToleranceTab              matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        LowFrequencyNoisePanel         matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        HighpassCheckBox               matlab.ui.control.CheckBox
        withsignificantinterceptGaugeLabel  matlab.ui.control.Label
        InterceptGauge                 matlab.ui.control.LinearGauge
        withsignificantslopeLabel      matlab.ui.control.Label
        SlopeGauge                     matlab.ui.control.LinearGauge
        LineNoisePanel                 matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        Notch60CheckBox                matlab.ui.control.CheckBox
        Notch120CheckBox               matlab.ui.control.CheckBox
        Notch180CheckBox               matlab.ui.control.CheckBox
        HighFrequencyNoisePanel        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        LowpassCheckBox                matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
        ConfirmFilterChoicesButton     matlab.ui.control.Button
        ArtifactRemovalTab             matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        UploadArtifactRemovalParameterMatrixButton  matlab.ui.control.Button
        ProcessAllButton               matlab.ui.control.Button
        channelsacceptedLabel          matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        Prev                           matlab.ui.control.Button
        Next                           matlab.ui.control.Button
        ACCEPTButton                   matlab.ui.control.StateButton
        trialspassLabel                matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        BracketRangeminEditFieldLabel  matlab.ui.control.Label
        BracketRangeminEditField       matlab.ui.control.NumericEditField
        DbscanSwitchLabel              matlab.ui.control.Label
        DbscanSwitch                   matlab.ui.control.Switch
        preEditFieldLabel              matlab.ui.control.Label
        preEditField                   matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        maxEditField                   matlab.ui.control.NumericEditField
        postEditFieldLabel             matlab.ui.control.Label
        postEditField                  matlab.ui.control.NumericEditField
        ProcessingTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        FiltersPanel                   matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Highpass01CheckBox             matlab.ui.control.CheckBox
        Notch60CheckBox_2              matlab.ui.control.CheckBox
        Notch120CheckBox_2             matlab.ui.control.CheckBox
        Notch180CheckBox_2             matlab.ui.control.CheckBox
        Lowpass500CheckBox             matlab.ui.control.CheckBox
        FILTERButton                   matlab.ui.control.Button
        NofiltersCheckBox              matlab.ui.control.CheckBox
        BaselineSubstitutionNormalizationPanel  matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        SUBTRACTANDNORMALIZEButton     matlab.ui.control.Button
        BaselineEditFieldLabel         matlab.ui.control.Label
        BaselineEditField              matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        toEditField                    matlab.ui.control.NumericEditField
        EpochingPanel                  matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        EPOCHButton                    matlab.ui.control.Button
        PreSliderLabel                 matlab.ui.control.Label
        PreSlider                      matlab.ui.control.Slider
        PostSliderLabel                matlab.ui.control.Label
        PostSlider                     matlab.ui.control.Slider
        ReReferencePanel               matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        SchemeButtonGroup              matlab.ui.container.ButtonGroup
        CommonMeanButton               matlab.ui.control.ToggleButton
        CommonMedianButton             matlab.ui.control.ToggleButton
        BipolarButton                  matlab.ui.control.ToggleButton
        NoneButton                     matlab.ui.control.ToggleButton
        REFERENCEButton                matlab.ui.control.Button
        SAVEASPRIMARYButton            matlab.ui.control.Button
        saveassecondaryButton          matlab.ui.control.Button
        FitModelsTab                   matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        UIAxes4                        matlab.ui.control.UIAxes
        UploadModelParameterMatrixButton  matlab.ui.control.Button
        FitAllButton                   matlab.ui.control.Button
        Prev_2                         matlab.ui.control.Button
        Next_2                         matlab.ui.control.Button
        R2Label                        matlab.ui.control.Label
        ACCEPTButton_2                 matlab.ui.control.StateButton
        MinMaxWidthsLabel              matlab.ui.control.Label
        C1EditFieldLabel               matlab.ui.control.Label
        C1minEditField                 matlab.ui.control.NumericEditField
        C1maxEditField                 matlab.ui.control.NumericEditField
        C2maxEditField                 matlab.ui.control.NumericEditField
        C2Label                        matlab.ui.control.Label
        C2minEditField                 matlab.ui.control.NumericEditField
        C3maxEditField                 matlab.ui.control.NumericEditField
        C3Label                        matlab.ui.control.Label
        C3minEditField                 matlab.ui.control.NumericEditField
        C4maxEditField                 matlab.ui.control.NumericEditField
        C4Label                        matlab.ui.control.Label
        C4minEditField                 matlab.ui.control.NumericEditField
        NComponentsButtonGroup         matlab.ui.container.ButtonGroup
        Button_1                       matlab.ui.control.ToggleButton
        Button_2                       matlab.ui.control.ToggleButton
        Button_3                       matlab.ui.control.ToggleButton
        Button_4                       matlab.ui.control.ToggleButton
        RunButton_2                    matlab.ui.control.Button
        channelsacceptedLabel_2        matlab.ui.control.Label
        C1PolaritySwitchLabel          matlab.ui.control.Label
        C1PolaritySwitch               matlab.ui.control.Switch
        QuantifyTab                    matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        MeanCheckBox                   matlab.ui.control.CheckBox
        EPTraceLabel                   matlab.ui.control.Label
        MedianCheckBox                 matlab.ui.control.CheckBox
        TrialQuantificationLabel       matlab.ui.control.Label
        trlAmplitudeCheckBox           matlab.ui.control.CheckBox
        trlLatencyCheckBox             matlab.ui.control.CheckBox
        trlRMSCheckBox                 matlab.ui.control.CheckBox
        MeanQuantificationLabel        matlab.ui.control.Label
        mnAmplitudeCheckBox            matlab.ui.control.CheckBox
        mnLatencyCheckBox              matlab.ui.control.CheckBox
        mnRMSCheckBox                  matlab.ui.control.CheckBox
        MedianQuantificationLabel      matlab.ui.control.Label
        medAmplitudeCheckBox           matlab.ui.control.CheckBox
        medLatencyCheckBox             matlab.ui.control.CheckBox
        medRMSCheckBox                 matlab.ui.control.CheckBox
        ProcessingParametersLabel      matlab.ui.control.Label
        QUANTIFYANDSAVEButton          matlab.ui.control.Button
        filenameEditFieldLabel         matlab.ui.control.Label
        filenameEditField              matlab.ui.control.EditField
        SubjectFileLabel               matlab.ui.control.Label
        LoadingLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        subjdir % Description
        filedir
        subj
        chans
        probes
        idx
        amplitude
        anode
        cathode
        data
        data_ctr
        zstd
        fs
        onsets_samps
        pulse_width
        badchans
        goodchans
        
        ManualInsAx
        ManualInsBut
        ManualInsCurPg
        ManualInsNPg
        ManualInsCurLns
        
        pw_data
        yp
        sp
        dist
        
    end
    
    methods (Access = private)
        
        function [] = ManualInspectionSetup(app)
            
            app.ManualInsAx = {app.UIAxes_1, app.UIAxes_2, app.UIAxes_3, ...
                app.UIAxes_4, app.UIAxes_5, app.UIAxes_6, app.UIAxes_7, ...
                app.UIAxes_8, app.UIAxes_9, app.UIAxes_10, app.UIAxes_11, ...
                app.UIAxes_12, app.UIAxes_13, app.UIAxes_14, app.UIAxes_15};
            app.ManualInsBut = {app.ChButton_1, app.ChButton_2, app.ChButton_3, ...
                app.ChButton_4, app.ChButton_5, app.ChButton_6, app.ChButton_7, ...
                app.ChButton_8, app.ChButton_9, app.ChButton_10, app.ChButton_11, ...
                app.ChButton_12, app.ChButton_13, app.ChButton_14, app.ChButton_15};
            
            app.data_ctr = app.data - median(app.data(1:(app.onsets_samps(1) - 1000), :));
            app.zstd = std(app.data_ctr(1:(app.onsets_samps(1) - 1000), :));
            
            app.ManualInsCurPg = 1;
            app.ManualInsNPg = ceil(size(app.data, 2)/15);

            app.BadChannelsLabel.Text = sprintf('Bad Channels: %s', app.formatBadChans);
                        
            app.ManualInspectionPlot;

        end
        
        function [] = NoiseInspectionSetup(app)
            
            cutoff = fdr(app.yp, .05);
            pct = sum(app.yp(app.goodchans) <= cutoff)/length(app.goodchans);
            app.InterceptGauge.Value = pct*100;
            cutoff = fdr(app.sp, .05);
            pct = sum(app.sp(app.goodchans) <= cutoff)/length(app.goodchans);
            app.SlopeGauge.Value = pct*100;
            
            plot(app.UIAxes, app.pw_data(:, app.goodchans));
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, mean(app.pw_data(:, app.goodchans), 2), 'k', 'LineWidth', 3);
            
%             histogram(app.UIAxes2, app.dist);
            
        end
        
        function [] = ManualInspectionPlot(app)
            
            if isequal(app.Switch.Value, 'Baseline Only')
                x = round(app.fs):(app.onsets_samps(1) - round(app.fs));
            else
                x = 1:size(app.data, 1);
            end
            
            idx1 = 1 + 15*(app.ManualInsCurPg - 1);
            idxend = min(idx1 + 14, size(app.data, 2));
            locidx = idx1:idxend;
            app.ManualInsCurLns = cell(1,length(locidx));
            for ii = 1:length(locidx)
                cla(app.ManualInsAx{ii});
                app.ManualInsAx{ii}.Visible = true;
                app.ManualInsBut{ii}.Visible = true;
                if ismember(locidx(ii), app.badchans)
                    app.ManualInsCurLns{ii} = plot(app.ManualInsAx{ii}, app.data_ctr(x, locidx(ii)), 'r');
                else
                    app.ManualInsCurLns{ii} = plot(app.ManualInsAx{ii}, app.data_ctr(x, locidx(ii)), 'k');
                end
                
                app.ManualInsBut{ii}.Text = sprintf('Ch. %d', locidx(ii));
                if ismember(locidx(ii), app.badchans)
                    app.ManualInsBut{ii}.Value = true;
                else
                    app.ManualInsBut{ii}.Value = false;
                end
            end
            if length(locidx) < 15
                for ii = (length(locidx)+1):15
                    cla(app.ManualInsAx{ii});
                    app.ManualInsAx{ii}.Visible = false;
                    app.ManualInsBut{ii}.Visible = false;
                end
            end
            
            if app.ManualInsCurPg == 1
                app.Left.Enable = false;
            else
                app.Left.Enable = true;
            end
            if app.ManualInsCurPg < app.ManualInsNPg
                app.Right.Enable = true;
            else
                app.Right.Enable = false;
            end
            
        end
        
        function badchans_f = formatBadChans(app)
            badchans_f = arrayfun(@(x) sprintf('%d, ', x), app.badchans(1:end-1), ...
                'UniformOutput', false);
            badchans_f = horzcat(badchans_f{:}, num2str(app.badchans(end)));
        end
        
        function [] = changeChanDes(app, n)
            value = app.ManualInsBut{n}.Value;
            ch = n + (15*(app.ManualInsCurPg - 1));
            if value
                app.badchans = sort(horzcat(app.badchans, ch));
                app.goodchans(app.goodchans == ch) = [];
                set(app.ManualInsCurLns{n}, 'Color', 'r');
            else
                app.badchans(app.badchans == ch) = [];
                app.goodchans = sort(horzcat(app.goodchans, ch));
                set(app.ManualInsCurLns{n}, 'Color', 'k');
            end
            app.BadChannelsLabel.Text = sprintf('Bad Channels: %s', app.formatBadChans);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, foldername, subj, basedir, chans, probes, idx)
            app.subjdir = fullfile(basedir, subj);
            
            app.filedir = fullfile(basedir, subj, foldername);
            app.chans = chans;
            app.probes = probes;
            app.idx = idx;
            app.subj = subj;
            app.SubjectFileLabel.Text = sprintf('Subject %s, File %s', app.subj, ...
                foldername);
            [ret, fn] = hasValidDataFile(app.filedir);
            
            if ret
                fn = fn{1};
                if isfile(fullfile(app.filedir, 'badChans.mat')) && ...
                        isfile(fullfile(app.filedir, 'noise_vals.mat')) && ...
                        isfile(fullfile(app.filedir, 'rs_data.mat'))
                    app.LoadingLabel.Text= 'Loading previously entered information';
                    s = load(fullfile(app.filedir, fn));
                    app.amplitude = s.amplitude; app.anode = s.anode; app.cathode = s.cathode;
                    app.data = s.data; app.fs = s.fs; app.onsets_samps = s.onsets_samps;
                    app.pulse_width = s.pulse_width; clearvars s
                    s = load(fullfile(app.filedir, 'badChans.mat'));
                    app.badchans = s.badchans; app.goodchans = s.goodchans; clearvars s
                    s = load(fullfile(app.filedir, 'noise_vals.mat'));
                    app.pw_data = s.pw_data; app.yp = s.yp; app.sp = s.sp; 
%                     app.dist = s.dist; 
                    clearvars s
                else
                    app.LoadingLabel.Text= 'Setting up data file (see command line for updates)';
                    [app.amplitude, app.anode, app.cathode, app.data, app.fs, app.onsets_samps, app.pulse_width, ...
                        app.badchans, app.goodchans, app.pw_data, app.yp, app.sp] = ..., app.dist] = ...
                        newFileCalcs(app.filedir, fn);
                end
                app.LoadingLabel.Visible = false;
                app.TabGroup.Visible = true;
                app.ManualInspectionSetup;
                app.NoiseInspectionSetup;
            else
                app.LoadingLabel.Text = 'No file found';
            end
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            app.ManualInsCurPg = app.ManualInsCurPg + 1;
            app.ManualInspectionPlot;
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            app.ManualInsCurPg = app.ManualInsCurPg - 1;
            app.ManualInspectionPlot;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.ManualInspectionPlot;            
        end

        % Value changed function: ChButton_1
        function ChButton_1ValueChanged(app, event)
            n = 1;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_2
        function ChButton_2ValueChanged(app, event)
            n = 2;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_3
        function ChButton_3ValueChanged(app, event)
            n = 3;
            app.changeChanDes(n);            
        end

        % Value changed function: ChButton_4
        function ChButton_4ValueChanged(app, event)
            n = 4;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_5
        function ChButton_5ValueChanged(app, event)
            n = 5;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_6
        function ChButton_6ValueChanged(app, event)
            n = 6;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_7
        function ChButton_7ValueChanged(app, event)
            n = 7;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_8
        function ChButton_8ValueChanged(app, event)
            n = 8;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_9
        function ChButton_9ValueChanged(app, event)
            n = 9;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_10
        function ChButton_10ValueChanged(app, event)
            n = 10;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_11
        function ChButton_11ValueChanged(app, event)
            n = 11;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_12
        function ChButton_12ValueChanged(app, event)
            n = 12;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_13
        function ChButton_13ValueChanged(app, event)
            n = 13;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_14
        function ChButton_14ValueChanged(app, event)
            n = 14;
            app.changeChanDes(n);
        end

        % Value changed function: ChButton_15
        function ChButton_15ValueChanged(app, event)
            n = 15;
            app.changeChanDes(n);
        end

        % Button pushed function: ConfirmBadChannelsButton
        function ConfirmBadChannelsButtonPushed(app, event)
            badchans = app.badchans;
            goodchans = app.goodchans;
            save(fullfile(app.filedir, 'badChans.mat'), 'badchans', 'goodchans');
            app.TabGroup.SelectedTab = app.NoiseToleranceTab;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ManualInspectionTab = uitab(app.TabGroup);
            app.ManualInspectionTab.Title = 'Manual Inspection';
            app.GridLayout = uigridlayout(app.ManualInspectionTab);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.ChButton_1 = uibutton(app.GridLayout, 'state');
            app.ChButton_1.ValueChangedFcn = createCallbackFcn(app, @ChButton_1ValueChanged, true);
            app.ChButton_1.Text = 'Ch.';
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ChButton_2 = uibutton(app.GridLayout, 'state');
            app.ChButton_2.ValueChangedFcn = createCallbackFcn(app, @ChButton_2ValueChanged, true);
            app.ChButton_2.Text = 'Ch.';
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.ChButton_3 = uibutton(app.GridLayout, 'state');
            app.ChButton_3.ValueChangedFcn = createCallbackFcn(app, @ChButton_3ValueChanged, true);
            app.ChButton_3.Text = 'Ch.';
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.ChButton_4 = uibutton(app.GridLayout, 'state');
            app.ChButton_4.ValueChangedFcn = createCallbackFcn(app, @ChButton_4ValueChanged, true);
            app.ChButton_4.Text = 'Ch.';
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.ChButton_5 = uibutton(app.GridLayout, 'state');
            app.ChButton_5.ValueChangedFcn = createCallbackFcn(app, @ChButton_5ValueChanged, true);
            app.ChButton_5.Text = 'Ch.';
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.ChButton_6 = uibutton(app.GridLayout, 'state');
            app.ChButton_6.ValueChangedFcn = createCallbackFcn(app, @ChButton_6ValueChanged, true);
            app.ChButton_6.Text = 'Ch.';
            app.UIAxes_7 = uiaxes(app.GridLayout);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.ChButton_7 = uibutton(app.GridLayout, 'state');
            app.ChButton_7.ValueChangedFcn = createCallbackFcn(app, @ChButton_7ValueChanged, true);
            app.ChButton_7.Text = 'Ch.';
            app.UIAxes_8 = uiaxes(app.GridLayout);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.ChButton_8 = uibutton(app.GridLayout, 'state');
            app.ChButton_8.ValueChangedFcn = createCallbackFcn(app, @ChButton_8ValueChanged, true);
            app.ChButton_8.Text = 'Ch.';
            app.UIAxes_9 = uiaxes(app.GridLayout);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.ChButton_9 = uibutton(app.GridLayout, 'state');
            app.ChButton_9.ValueChangedFcn = createCallbackFcn(app, @ChButton_9ValueChanged, true);
            app.ChButton_9.Text = 'Ch.';
            app.UIAxes_10 = uiaxes(app.GridLayout);
            title(app.UIAxes_10, '')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.ChButton_10 = uibutton(app.GridLayout, 'state');
            app.ChButton_10.ValueChangedFcn = createCallbackFcn(app, @ChButton_10ValueChanged, true);
            app.ChButton_10.Text = 'Ch.';
            app.UIAxes_11 = uiaxes(app.GridLayout);
            title(app.UIAxes_11, '')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, '')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.ChButton_11 = uibutton(app.GridLayout, 'state');
            app.ChButton_11.ValueChangedFcn = createCallbackFcn(app, @ChButton_11ValueChanged, true);
            app.ChButton_11.Text = 'Ch.';
            app.UIAxes_12 = uiaxes(app.GridLayout);
            title(app.UIAxes_12, '')
            xlabel(app.UIAxes_12, '')
            ylabel(app.UIAxes_12, '')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ChButton_12 = uibutton(app.GridLayout, 'state');
            app.ChButton_12.ValueChangedFcn = createCallbackFcn(app, @ChButton_12ValueChanged, true);
            app.ChButton_12.Text = 'Ch.';
            app.UIAxes_13 = uiaxes(app.GridLayout);
            title(app.UIAxes_13, '')
            xlabel(app.UIAxes_13, '')
            ylabel(app.UIAxes_13, '')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.ChButton_13 = uibutton(app.GridLayout, 'state');
            app.ChButton_13.ValueChangedFcn = createCallbackFcn(app, @ChButton_13ValueChanged, true);
            app.ChButton_13.Text = 'Ch.';
            app.UIAxes_14 = uiaxes(app.GridLayout);
            title(app.UIAxes_14, '')
            xlabel(app.UIAxes_14, '')
            ylabel(app.UIAxes_14, '')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.ChButton_14 = uibutton(app.GridLayout, 'state');
            app.ChButton_14.ValueChangedFcn = createCallbackFcn(app, @ChButton_14ValueChanged, true);
            app.ChButton_14.Text = 'Ch.';
            app.UIAxes_15 = uiaxes(app.GridLayout);
            title(app.UIAxes_15, '')
            xlabel(app.UIAxes_15, '')
            ylabel(app.UIAxes_15, '')
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.ChButton_15 = uibutton(app.GridLayout, 'state');
            app.ChButton_15.ValueChangedFcn = createCallbackFcn(app, @ChButton_15ValueChanged, true);
            app.ChButton_15.Text = 'Ch.';
            app.BadChannelsLabel = uilabel(app.GridLayout);
            app.BadChannelsLabel.Text = 'Bad Channels: ';
            app.Left = uibutton(app.GridLayout, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '<';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'Full Signal', 'Baseline Only'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Baseline Only';
            app.Right = uibutton(app.GridLayout, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '>';
            app.ConfirmBadChannelsButton = uibutton(app.GridLayout, 'push');
            app.ConfirmBadChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmBadChannelsButtonPushed, true);
            app.ConfirmBadChannelsButton.Text = 'Confirm Bad Channels';
            app.NoiseToleranceTab = uitab(app.TabGroup);
            app.NoiseToleranceTab.Title = 'Noise Tolerance';
            app.GridLayout2 = uigridlayout(app.NoiseToleranceTab);
            app.LowFrequencyNoisePanel = uipanel(app.GridLayout2);
            app.LowFrequencyNoisePanel.Title = 'Low Frequency Noise';
            app.GridLayout3 = uigridlayout(app.LowFrequencyNoisePanel);
            app.HighpassCheckBox = uicheckbox(app.GridLayout3);
            app.HighpassCheckBox.Text = 'Highpass';
            app.withsignificantinterceptGaugeLabel = uilabel(app.GridLayout3);
            app.withsignificantinterceptGaugeLabel.Text = '% with significant intercept';
            app.InterceptGauge = uigauge(app.GridLayout3, 'linear');
            app.InterceptGauge.MajorTicks = [0 20 40 60 80 100];
            app.InterceptGauge.MajorTickLabels = {''};
            app.withsignificantslopeLabel = uilabel(app.GridLayout3);
            app.withsignificantslopeLabel.Text = '% with significant slope';
            app.SlopeGauge = uigauge(app.GridLayout3, 'linear');
            app.SlopeGauge.MajorTicks = [0 20 40 60 80 100];
            app.SlopeGauge.MajorTickLabels = {''};
            app.LineNoisePanel = uipanel(app.GridLayout2);
            app.LineNoisePanel.Title = 'Line Noise';
            app.GridLayout4 = uigridlayout(app.LineNoisePanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'frequency')
            ylabel(app.UIAxes, 'dB/Hz')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Notch60CheckBox = uicheckbox(app.GridLayout4);
            app.Notch60CheckBox.Text = 'Notch 60';
            app.Notch120CheckBox = uicheckbox(app.GridLayout4);
            app.Notch120CheckBox.Text = 'Notch 120';
            app.Notch180CheckBox = uicheckbox(app.GridLayout4);
            app.Notch180CheckBox.Text = 'Notch 180';
            app.HighFrequencyNoisePanel = uipanel(app.GridLayout2);
            app.HighFrequencyNoisePanel.Title = 'High Frequency Noise';
            app.GridLayout5 = uigridlayout(app.HighFrequencyNoisePanel);
            app.LowpassCheckBox = uicheckbox(app.GridLayout5);
            app.LowpassCheckBox.Text = 'Lowpass';
            app.UIAxes2 = uiaxes(app.GridLayout5);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.ConfirmFilterChoicesButton = uibutton(app.GridLayout2, 'push');
            app.ConfirmFilterChoicesButton.Text = 'Confirm Filter Choices';
            app.ArtifactRemovalTab = uitab(app.TabGroup);
            app.ArtifactRemovalTab.Title = 'Artifact Removal';
            app.GridLayout6 = uigridlayout(app.ArtifactRemovalTab);
            app.UploadArtifactRemovalParameterMatrixButton = uibutton(app.GridLayout6, 'push');
            app.UploadArtifactRemovalParameterMatrixButton.Text = 'Upload Artifact Removal Parameter Matrix';
            app.ProcessAllButton = uibutton(app.GridLayout6, 'push');
            app.ProcessAllButton.Text = 'Process All';
            app.channelsacceptedLabel = uilabel(app.GridLayout6);
            app.channelsacceptedLabel.Text = '/ channels accepted';
            app.UIAxes3 = uiaxes(app.GridLayout6);
            title(app.UIAxes3, 'Ch. ')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.Prev = uibutton(app.GridLayout6, 'push');
            app.Prev.Text = '<';
            app.Next = uibutton(app.GridLayout6, 'push');
            app.Next.Text = '>';
            app.ACCEPTButton = uibutton(app.GridLayout6, 'state');
            app.ACCEPTButton.Text = 'ACCEPT';
            app.trialspassLabel = uilabel(app.GridLayout6);
            app.trialspassLabel.Text = '/ trials pass';
            app.RunButton = uibutton(app.GridLayout6, 'push');
            app.RunButton.Text = 'Run';
            app.BracketRangeminEditFieldLabel = uilabel(app.GridLayout6);
            app.BracketRangeminEditFieldLabel.Text = {'Bracket Range'; 'min'};
            app.BracketRangeminEditField = uieditfield(app.GridLayout6, 'numeric');
            app.DbscanSwitchLabel = uilabel(app.GridLayout6);
            app.DbscanSwitchLabel.Text = 'Dbscan';
            app.DbscanSwitch = uiswitch(app.GridLayout6, 'slider');
            app.DbscanSwitch.Items = {'Corr', 'Eucl'};
            app.DbscanSwitch.Value = 'Corr';
            app.preEditFieldLabel = uilabel(app.GridLayout6);
            app.preEditFieldLabel.Text = 'pre';
            app.preEditField = uieditfield(app.GridLayout6, 'numeric');
            app.maxEditFieldLabel = uilabel(app.GridLayout6);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout6, 'numeric');
            app.postEditFieldLabel = uilabel(app.GridLayout6);
            app.postEditFieldLabel.Text = 'post';
            app.postEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.GridLayout7 = uigridlayout(app.ProcessingTab);
            app.FiltersPanel = uipanel(app.GridLayout7);
            app.FiltersPanel.Title = 'Filters';
            app.GridLayout8 = uigridlayout(app.FiltersPanel);
            app.Highpass01CheckBox = uicheckbox(app.GridLayout8);
            app.Highpass01CheckBox.Text = 'Highpass 0.1';
            app.Notch60CheckBox_2 = uicheckbox(app.GridLayout8);
            app.Notch60CheckBox_2.Text = 'Notch 60';
            app.Notch120CheckBox_2 = uicheckbox(app.GridLayout8);
            app.Notch120CheckBox_2.Text = 'Notch 120';
            app.Notch180CheckBox_2 = uicheckbox(app.GridLayout8);
            app.Notch180CheckBox_2.Text = 'Notch 180';
            app.Lowpass500CheckBox = uicheckbox(app.GridLayout8);
            app.Lowpass500CheckBox.Text = 'Lowpass 500';
            app.FILTERButton = uibutton(app.GridLayout8, 'push');
            app.FILTERButton.Text = 'FILTER';
            app.NofiltersCheckBox = uicheckbox(app.GridLayout8);
            app.NofiltersCheckBox.Text = 'No filters';
            app.BaselineSubstitutionNormalizationPanel = uipanel(app.GridLayout7);
            app.BaselineSubstitutionNormalizationPanel.Title = 'Baseline Substitution/Normalization';
            app.GridLayout10 = uigridlayout(app.BaselineSubstitutionNormalizationPanel);
            app.SUBTRACTANDNORMALIZEButton = uibutton(app.GridLayout10, 'push');
            app.SUBTRACTANDNORMALIZEButton.Text = 'SUBTRACT AND NORMALIZE';
            app.BaselineEditFieldLabel = uilabel(app.GridLayout10);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.GridLayout10, 'numeric');
            app.BaselineEditField.Value = -0.4;
            app.toEditFieldLabel = uilabel(app.GridLayout10);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.GridLayout10, 'numeric');
            app.toEditField.Value = -0.1;
            app.EpochingPanel = uipanel(app.GridLayout7);
            app.EpochingPanel.Title = 'Epoching';
            app.GridLayout9 = uigridlayout(app.EpochingPanel);
            app.EPOCHButton = uibutton(app.GridLayout9, 'push');
            app.EPOCHButton.Text = 'EPOCH';
            app.PreSliderLabel = uilabel(app.GridLayout9);
            app.PreSliderLabel.Text = 'Pre';
            app.PreSlider = uislider(app.GridLayout9);
            app.PreSlider.Limits = [-2 0];
            app.PreSlider.MajorTicks = [-2 -1.75 -1.5 -1.25 -1 -0.75 -0.5 -0.25 0];
            app.PreSlider.MinorTicks = [];
            app.PreSlider.Value = -0.5;
            app.PostSliderLabel = uilabel(app.GridLayout9);
            app.PostSliderLabel.Text = 'Post';
            app.PostSlider = uislider(app.GridLayout9);
            app.PostSlider.Limits = [0 2];
            app.PostSlider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2];
            app.PostSlider.MinorTicks = [];
            app.PostSlider.Value = 1;
            app.ReReferencePanel = uipanel(app.GridLayout7);
            app.ReReferencePanel.Title = 'Re-Reference';
            app.GridLayout11 = uigridlayout(app.ReReferencePanel);
            app.SchemeButtonGroup = uibuttongroup(app.GridLayout11);
            app.SchemeButtonGroup.Title = 'Scheme';
            app.CommonMeanButton = uitogglebutton(app.SchemeButtonGroup);
            app.CommonMeanButton.Text = 'Common Mean';
            app.CommonMedianButton = uitogglebutton(app.SchemeButtonGroup);
            app.CommonMedianButton.Text = 'Common Median';
            app.BipolarButton = uitogglebutton(app.SchemeButtonGroup);
            app.BipolarButton.Text = 'Bipolar';
            app.BipolarButton.Value = true;
            app.NoneButton = uitogglebutton(app.SchemeButtonGroup);
            app.NoneButton.Text = 'None';
            app.REFERENCEButton = uibutton(app.GridLayout11, 'push');
            app.REFERENCEButton.Text = 'REFERENCE';
            app.SAVEASPRIMARYButton = uibutton(app.GridLayout7, 'push');
            app.SAVEASPRIMARYButton.Text = 'SAVE AS PRIMARY';
            app.saveassecondaryButton = uibutton(app.GridLayout7, 'push');
            app.saveassecondaryButton.Text = 'save as secondary';
            app.FitModelsTab = uitab(app.TabGroup);
            app.FitModelsTab.Title = 'Fit Models';
            app.GridLayout12 = uigridlayout(app.FitModelsTab);
            app.UIAxes4 = uiaxes(app.GridLayout12);
            title(app.UIAxes4, 'Ch. ')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UploadModelParameterMatrixButton = uibutton(app.GridLayout12, 'push');
            app.UploadModelParameterMatrixButton.Text = 'Upload Model Parameter Matrix';
            app.FitAllButton = uibutton(app.GridLayout12, 'push');
            app.FitAllButton.Text = 'Fit All';
            app.Prev_2 = uibutton(app.GridLayout12, 'push');
            app.Prev_2.Text = '<';
            app.Next_2 = uibutton(app.GridLayout12, 'push');
            app.Next_2.Text = '>';
            app.R2Label = uilabel(app.GridLayout12);
            app.R2Label.Text = 'R2 =';
            app.ACCEPTButton_2 = uibutton(app.GridLayout12, 'state');
            app.ACCEPTButton_2.Text = 'ACCEPT';
            app.MinMaxWidthsLabel = uilabel(app.GridLayout12);
            app.MinMaxWidthsLabel.Text = 'Min/Max Widths';
            app.C1EditFieldLabel = uilabel(app.GridLayout12);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1minEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C1maxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C2maxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C2Label = uilabel(app.GridLayout12);
            app.C2Label.Text = 'C2';
            app.C2minEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C3maxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C3Label = uilabel(app.GridLayout12);
            app.C3Label.Text = 'C3';
            app.C3minEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C4maxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.C4Label = uilabel(app.GridLayout12);
            app.C4Label.Text = 'C4';
            app.C4minEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NComponentsButtonGroup = uibuttongroup(app.GridLayout12);
            app.NComponentsButtonGroup.Title = 'N Components';
            app.Button_1 = uitogglebutton(app.NComponentsButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uitogglebutton(app.NComponentsButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.NComponentsButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.NComponentsButtonGroup);
            app.Button_4.Text = '4';
            app.RunButton_2 = uibutton(app.GridLayout12, 'push');
            app.RunButton_2.Text = 'Run';
            app.channelsacceptedLabel_2 = uilabel(app.GridLayout12);
            app.channelsacceptedLabel_2.Text = '/ channels accepted';
            app.C1PolaritySwitchLabel = uilabel(app.GridLayout12);
            app.C1PolaritySwitchLabel.Text = 'C1 Polarity';
            app.C1PolaritySwitch = uiswitch(app.GridLayout12, 'slider');
            app.C1PolaritySwitch.Items = {'-', '+'};
            app.C1PolaritySwitch.Value = '-';
            app.QuantifyTab = uitab(app.TabGroup);
            app.QuantifyTab.Title = 'Quantify';
            app.GridLayout13 = uigridlayout(app.QuantifyTab);
            app.MeanCheckBox = uicheckbox(app.GridLayout13);
            app.MeanCheckBox.Text = 'Mean';
            app.EPTraceLabel = uilabel(app.GridLayout13);
            app.EPTraceLabel.Text = 'EP Trace';
            app.MedianCheckBox = uicheckbox(app.GridLayout13);
            app.MedianCheckBox.Text = 'Median';
            app.TrialQuantificationLabel = uilabel(app.GridLayout13);
            app.TrialQuantificationLabel.Text = 'Trial Quantification';
            app.trlAmplitudeCheckBox = uicheckbox(app.GridLayout13);
            app.trlAmplitudeCheckBox.Text = 'Amplitude';
            app.trlLatencyCheckBox = uicheckbox(app.GridLayout13);
            app.trlLatencyCheckBox.Text = 'Latency';
            app.trlRMSCheckBox = uicheckbox(app.GridLayout13);
            app.trlRMSCheckBox.Text = 'RMS';
            app.MeanQuantificationLabel = uilabel(app.GridLayout13);
            app.MeanQuantificationLabel.Text = 'Mean Quantification';
            app.mnAmplitudeCheckBox = uicheckbox(app.GridLayout13);
            app.mnAmplitudeCheckBox.Text = 'Amplitude';
            app.mnLatencyCheckBox = uicheckbox(app.GridLayout13);
            app.mnLatencyCheckBox.Text = 'Latency';
            app.mnRMSCheckBox = uicheckbox(app.GridLayout13);
            app.mnRMSCheckBox.Text = 'RMS';
            app.MedianQuantificationLabel = uilabel(app.GridLayout13);
            app.MedianQuantificationLabel.Text = 'Median Quantification';
            app.medAmplitudeCheckBox = uicheckbox(app.GridLayout13);
            app.medAmplitudeCheckBox.Text = 'Amplitude';
            app.medLatencyCheckBox = uicheckbox(app.GridLayout13);
            app.medLatencyCheckBox.Text = 'Latency';
            app.medRMSCheckBox = uicheckbox(app.GridLayout13);
            app.medRMSCheckBox.Text = 'RMS';
            app.ProcessingParametersLabel = uilabel(app.GridLayout13);
            app.ProcessingParametersLabel.Text = 'Processing Parameters:';
            app.QUANTIFYANDSAVEButton = uibutton(app.GridLayout13, 'push');
            app.QUANTIFYANDSAVEButton.Text = 'QUANTIFY AND SAVE';
            app.filenameEditFieldLabel = uilabel(app.GridLayout13);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.GridLayout13, 'text');
            app.SubjectFileLabel = uilabel(app.UIFigure);
            app.SubjectFileLabel.Text = 'Subject, File';
            app.LoadingLabel = uilabel(app.UIFigure);
            app.LoadingLabel.Text = 'Loading data file...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCEP_file(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end